classdef SheepHerdingGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SheepHerdingWindow     matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        StartButton            matlab.ui.control.Button
        ButtonGroup            matlab.ui.container.ButtonGroup
        Level1Button           matlab.ui.control.RadioButton
        Level2Button           matlab.ui.control.RadioButton
        Level3Button           matlab.ui.control.RadioButton
        Level4Button           matlab.ui.control.RadioButton
        TopScoresListBoxLabel  matlab.ui.control.Label
        TopScoresListBox       matlab.ui.control.ListBox
        ViewTopScoreButton     matlab.ui.control.Button
        EnterNameField         matlab.ui.control.EditField
        img_dog                matlab.ui.control.Image
        img_target             matlab.ui.control.Image
    end

    
    properties (Access = private)
        myTimer;
        Player1_Dir = [0;0];
        Player1_moveL = false;
        Player1_moveR = false;
        Player1_moveU = false;
        Player1_moveD = false;
        time = 0;
        cost = 0;
        LevelCompleted = 0;
        dt = 0.1;
        dt_max = 0.2;
        started = false;
        
        level = 1;
        replay = 0;
        replay_time = 1;
        img_sheeps;
        max_sheeps = 7;
        img_poles;
        max_poles = 15;
        
        % level dependent variables
        Player1_Pos;
        Player_speed;
        N;
        SheepX;
        SheepY;
        SheepvX;
        SheepvY;
        Obstacles;
        Target;
        weight;
        coeffs;
        target_radius;
        target_speed;
        history = [];
    end
    
    methods (Access = public)
        
        function update_this(app,timer,~)
            if ~isnan(timer.InstantPeriod)
                if app.started
                    %                 dt = min([timer.InstantPeriod, app.dt_max]);
                    dt = app.dt;
                    app.Player1_Dir = [app.Player1_moveR - app.Player1_moveL,
                        app.Player1_moveU - app.Player1_moveD];
                    app.Player1_Pos = app.Player1_Pos + app.Player_speed*dt.*app.Player1_Dir;
                    update_sheep(app,dt);
                    update_cost(app,dt);
                    app.time = app.time + dt;
                    app.history = [app.history, app.Player1_Dir];
                    if is_level_completed(app) && app.started && ~app.replay
                        app.LevelCompleted = true;
                        newscore.name = app.EnterNameField.Value;
                        newscore.u = app.history;
                        newscore.J = app.cost;
                        add_score(app, newscore);
                        display_scores(app);
                        app.StartButton.Text = 'Start';
                        app.started = false;
                        app.replay = false;
                    end
                    plotting(app);
                elseif app.replay
                    dt = app.dt;
                    app.Player1_Dir = app.history(:,app.replay_time);
                    app.Player1_Pos = app.Player1_Pos + app.Player_speed*dt*app.Player1_Dir;
                    update_sheep(app,dt);
                    update_cost(app,dt);
                    app.time = app.time + dt;
                    app.replay_time = app.replay_time + 1;
                    if is_level_completed(app) || app.replay_time > size(app.history, 2)
                        app.LevelCompleted = true;
                        app.started = false;
                        app.replay = false;
                        app.StartButton.Text = 'Start';
                        app.ViewTopScoreButton.Text = 'View Top Score';
                    end
                    plotting(app);
                end
            end
        end
        
        function update_sheep(app,dt)
            [fX, fY] = sheep_dynamics(app.SheepX, app.SheepY, app.SheepvX, app.SheepvY, ...
                app.N, app.Player1_Pos, app.Obstacles, app.coeffs);
            
            app.SheepvX = app.SheepvX + dt*fX;
            app.SheepvY = app.SheepvY + dt*fY;
            
            app.SheepX = app.SheepX + dt*app.SheepvX;
            app.SheepY = app.SheepY + dt*app.SheepvY;
        end
        
        function update_cost(app,dt)
            Target_Pos = app.Target(app.time);
            dcost = norm(app.SheepX - Target_Pos(1))^2 + norm(app.SheepY - Target_Pos(2))^2;
            dcost = dcost/app.N + app.weight*norm(app.Player1_Dir)^2;
            app.cost  = app.cost + dcost*dt;
        end
        
        function bool = is_level_completed(app)
            sheep_dist  = zeros(1, app.N);
            sheep_speed = zeros(1, app.N);
            Target_Pos = app.Target(app.time);
            for ii = 1:app.N
                sheep_dist(ii)  = sqrt((app.SheepX(ii) - Target_Pos(1))^2 ...
                    + (app.SheepY(ii) - Target_Pos(2))^2);
                sheep_speed(ii) = sqrt(app.SheepvX(ii)^2 + app.SheepvY(ii)^2);
            end
            bool = all(sheep_dist < app.target_radius(app.time) & ...
                sheep_speed < app.target_speed(app.time));
        end
        
        function load_level(app,level)
            filename = ['load_level', num2str(level), '_data'];
            run(filename);
            
            app.Player1_Pos = Player1_Pos;
            app.Player_speed = Player_speed;
            app.N = N;
            app.SheepX = SheepX;
            app.SheepY = SheepY;
            app.SheepvX = SheepvX;
            app.SheepvY = SheepvY;
            app.Obstacles = Obstacles;
            app.Target = Target;
            app.weight = weight;
            app.coeffs = coeffs;
            app.target_radius = target_radius;
            app.target_speed  = target_speed;
            
            app.time = 0;
            app.cost = 0;
            app.LevelCompleted = 0;
            
            POS = app.UIAxes.Position;
            app.img_dog.Position = [POS(1)+POS(3)/2+POS(4)*(app.Player1_Pos(1) - 0.25)/6, ...
                POS(2)+POS(4)/2 + POS(4)*(app.Player1_Pos(2) - 0.25)/6 - 12.5, POS(4)*0.5/6, POS(4)*0.5/6];
            target0 = app.Target(0);
            app.img_target.Position = [POS(1)+POS(3)/2+POS(4)*(target0(1) - 2*app.target_radius(0))/6, ...
                POS(2)+POS(4)/2 + POS(4)*(target0(2) - 2*app.target_radius(0))/6, ...
                POS(4)*app.target_radius(0)/3, POS(4)*app.target_radius(0)/3];
            
            for ii = 1:app.N
                app.img_sheeps{ii}.Position = [(POS(1)+POS(3)/2) + POS(4)*(app.SheepX(ii)-0.2)/6,(POS(2)+POS(4)/2) + POS(4)*(app.SheepY(ii)-0.2)/6,POS(4)*0.4/6,POS(4)*0.4/6];
                app.img_sheeps{ii}.Visible = true;
            end
            for ii = app.N+1:app.max_sheeps
                app.img_sheeps{ii}.Visible = false;
            end
            
            hold(app.UIAxes, 'off')
            plot(app.UIAxes,-5,5);
            hold(app.UIAxes, 'on')
            
            for ii = 1:app.max_poles
                app.img_poles{ii}.Visible = false;
            end
            
            drawObstacles(app);
        end
        
        function add_score(app, newscore)
            filename = ['scores_level', num2str(app.level)];
            load(filename, 'scores', 'optimizer_ind');
            
            Jmax = 0; score_max = length(scores)+1;
            if length(scores) >= 5
                for ii = 1:length(scores)
                    if scores(ii).J > Jmax
                        Jmax = scores(ii).J;
                        score_max = ii;
                    end
                end
            end
            if score_max <= 5 || scores(score_max).J > newscore.J
                scores(score_max).name = newscore.name;
                scores(score_max).J    = newscore.J;
                scores(score_max).u    = newscore.u;
            end
            if score_max == optimizer_ind
                optimizer_ind = [];
            end
            
            save(filename, 'scores', 'optimizer_ind');
        end
        
        function display_scores(app)
            filename = ['scores_level', num2str(app.level)];
            load(filename, 'scores');
            
            J_list = [];
            for ii = 1:length(scores)
                J_list = [J_list, scores(ii).J];
            end
            [J_list, ind] = sort(J_list);
            Items = [];
            for ii = 1:length(scores)
                Items{ii} = [num2str(J_list(ii),'%.2f'), ' ', scores(ind(ii)).name];
            end
            for ii = length(scores)+1:5
                Items{ii} = '   --- empty ---';
            end
            app.TopScoresListBox.Items = Items;
        end
        
        function drawObstacles(app)
            kk = 1;
            POS = app.UIAxes.InnerPosition;
            for ii = 1:length(app.Obstacles)
                L = norm(app.Obstacles(ii).x1 - app.Obstacles(ii).x2);
                plot(app.UIAxes, [app.Obstacles(ii).x1(1), app.Obstacles(ii).x2(1)], [app.Obstacles(ii).x1(2), app.Obstacles(ii).x2(2)], 'k')
                plot(app.UIAxes, [app.Obstacles(ii).x1(1), app.Obstacles(ii).x2(1)], [app.Obstacles(ii).x1(2), app.Obstacles(ii).x2(2)]+0.1, 'k')
                np = ceil(L/1.5) + 1;
                s = linspace(0,1,np);
                for jj = 1:np
                    xjj = app.Obstacles(ii).x1 + s(jj)*(app.Obstacles(ii).x2 - app.Obstacles(ii).x1);
%                                             plot(app.UIAxes, xjj(1), xjj(2), 'ko')
                    app.img_poles{kk}.Position =  [(POS(1)+POS(3)/2) + POS(4)*(xjj(1)-0.2)/6,(POS(2)+POS(4)/2) + POS(4)*(xjj(2)-0.5)/6,POS(4)*0.4/6,POS(4)*1/6];
                    app.img_poles{kk}.Visible = true;
                    kk = kk + 1;
                end
            end
            Target_Pos = app.Target(0);
            %                 plot(app.UIAxes,Target_Pos(1),Target_Pos(2), 'x', 'Color', '#A2142F');
            %                 theta = linspace(0,2*pi,100);
            %                 plot(app.UIAxes, Target_Pos(1)+app.target_radius(app.time)*cos(theta), ...
            %                     Target_Pos(2)+app.target_radius(app.time)*sin(theta), 'Color', '#A2142F')
            app.img_target.Position = [POS(1)+POS(3)/2+POS(4)*(Target_Pos(1) - app.target_radius(app.time))/6, ...
                POS(2)+POS(4)/2 + POS(4)*(Target_Pos(2) - app.target_radius(app.time))/6, ...
                POS(4)*app.target_radius(app.time)/3, POS(4)*app.target_radius(app.time)/3];
        end
        
        function plotting(app)
            if ~isempty(app.SheepX)
                POS = app.UIAxes.InnerPosition;
                %                 plot(app.UIAxes,app.SheepX,app.SheepY, 'bo');
                for ii = 1:app.N
                    app.img_sheeps{ii}.Position = [(POS(1)+POS(3)/2) + POS(4)*(app.SheepX(ii)-0.2)/6,(POS(2)+POS(4)/2) + POS(4)*(app.SheepY(ii)-0.2)/6,POS(4)*0.4/6,POS(4)*0.4/6];
                end
                %                 for oo = 1:length(app.Obstacles)
                %                     plot(app.UIAxes,[app.Obstacles(oo).x1(1), app.Obstacles(oo).x2(1)], ...
                %                         [app.Obstacles(oo).x1(2), app.Obstacles(oo).x2(2)], 'Color', '#D95319')
                %                 end
                plot(app.UIAxes,app.Player1_Pos(1),app.Player1_Pos(2), 'k.');
                app.img_dog.Position = [POS(1)+POS(3)/2+POS(4)*(app.Player1_Pos(1) - 0.25)/6, ...
                    POS(2)+POS(4)/2 + POS(4)*(app.Player1_Pos(2) - 0.25)/6, POS(4)*0.5/6, POS(4)*0.5/6];
                Target_Pos = app.Target(app.time);
                %                 plot(app.UIAxes,Target_Pos(1),Target_Pos(2), 'x', 'Color', '#A2142F');
                %                 theta = linspace(0,2*pi,100);
                %                 plot(app.UIAxes, Target_Pos(1)+app.target_radius(app.time)*cos(theta), ...
                %                     Target_Pos(2)+app.target_radius(app.time)*sin(theta), 'Color', '#A2142F')
                app.img_target.Position = [POS(1)+POS(3)/2+POS(4)*(Target_Pos(1) - app.target_radius(app.time))/6, ...
                    POS(2)+POS(4)/2 + POS(4)*(Target_Pos(2) - app.target_radius(app.time))/6, ...
                    POS(4)*app.target_radius(app.time)/3, POS(4)*app.target_radius(app.time)/3];
                if app.started
                    title_text{1} = 'Drive the sheep to the red circle';
                elseif app.replay
                    title_text{1} = 'REPLAY';
                end
                if app.LevelCompleted
                    title_text{2} = ['COMPLETED! cost = ', num2str(app.cost)];
                else
                    title_text{2} = ['cost = ', num2str(app.cost)];
                end
                app.UIAxes.Title.String = title_text;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XLim = [-3,3];
            app.UIAxes.YLim = [-3,3];
            
            % Create timer object
            app.myTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', app.dt, ...                     % Period is 1 second
                'BusyMode', 'drop',...              % Queue timer callbacks when busy
                'TimerFcn', @app.update_this);       % Specify callback function
            start(app.myTimer);
            
            load_level(app,app.level);
            display_scores(app);
            
            set(app.UIAxes, 'Color', [0, 0.95, 0]);
            app.UIAxes.XColor = 'w';
            app.UIAxes.YColor = 'w';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Interactions = [];
            app.UIAxes.Toolbar.Visible = 'off';
            
            
            for ii = 1:app.max_sheeps
                app.img_sheeps{ii} = uiimage(app.SheepHerdingWindow, 'ImageSource', 'img_sheep_alpha.png');
            end
            
            for ii = 1:app.max_poles
                app.img_poles{ii} = uiimage(app.SheepHerdingWindow, 'ImageSource', 'img_fence_post_alpha.png');
            end
            
            load_level(app,1)
        end

        % Window key press function: SheepHerdingWindow
        function SheepHerdingWindowWindowKeyPress(app, event)
            key = event.Key;
            if isequal(key, 'rightarrow')
                app.Player1_moveR = true;
            end
            if isequal(key, 'leftarrow')
                app.Player1_moveL = true;
            end
            if isequal(key, 'uparrow')
                app.Player1_moveU = true;
            end
            if isequal(key, 'downarrow')
                app.Player1_moveD = true;
            end
        end

        % Window key release function: SheepHerdingWindow
        function SheepHerdingWindowWindowKeyRelease(app, event)
            key = event.Key;
            if isequal(key, 'rightarrow')
                app.Player1_moveR = false;
            end
            if isequal(key, 'leftarrow')
                app.Player1_moveL = false;
            end
            if isequal(key, 'uparrow')
                app.Player1_moveU = false;
            end
            if isequal(key, 'downarrow')
                app.Player1_moveD = false;
            end
        end

        % Close request function: SheepHerdingWindow
        function SheepHerdingWindowCloseRequest(app, event)
            stop(app.myTimer);
            delete(app.myTimer);
            delete(app)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if ~app.started
                app.started = true;
                app.LevelCompleted = false;
                app.history = [];
                app.StartButton.Text = 'Stop';
                load_level(app,app.level);
            else
                app.started = false;
                app.LevelCompleted = false;
                app.StartButton.Text = 'Start';
            end
            app.replay = false;
            app.ViewTopScoreButton.Text = 'View Top Score';
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.started = false;
            app.replay = false;
            
            selectedButton = app.ButtonGroup.SelectedObject.Text;
            app.level = str2double(selectedButton(end));
            
            load_level(app,app.level);
            display_scores(app);
            app.StartButton.Text = 'Start';
            app.ViewTopScoreButton.Text = 'View Top Score';
        end

        % Button pushed function: ViewTopScoreButton
        function ViewTopScoreButtonPushed(app, event)
            app.started = false;
            app.StartButton.Text = 'Start';
            if ~app.replay
                label = app.TopScoresListBox.Value;
                C = strsplit(label,' ');
                
                filename = ['scores_level', num2str(app.level)];
                load(filename, 'scores');
                for ii = 1:length(scores)
                    if isequal(num2str(scores(ii).J,'%.2f'), C{1}) && isequal(scores(ii).name, C{2})
                        app.history = scores(ii).u;
                        app.replay = true;
                        app.replay_time = 1;
                        load_level(app,app.level);
                        app.ViewTopScoreButton.Text = 'Stop replay';
                    end
                end
            else
                app.replay = false;
                app.ViewTopScoreButton.Text = 'View Top Score';
                app.StartButton.Text = 'Start';
            end
        end

        % Value changed function: TopScoresListBox
        function TopScoresListBoxValueChanged(app, event)
            app.replay = false;
            app.started = false;
            app.ViewTopScoreButton.Text = 'View Top Score';
            app.StartButton.Text = 'Start';
        end

        % Size changed function: SheepHerdingWindow
        function SheepHerdingWindowSizeChanged(app, event)
            position = app.SheepHerdingWindow.Position;
            
            app.EnterNameField.Position   = [position(3)-150, position(4)-50 , 125, 22];
            app.ButtonGroup.Position = [position(3)-150, position(4)-165, 125, 95];
            app.StartButton.Position = [position(3)-150, position(4)-192, 125, 22];
            app.TopScoresListBoxLabel.Position = [position(3)-150, position(4)-255, 125, 22];
            app.TopScoresListBox.Position      = [position(3)-150, position(4)-350, 125, 95];
            app.ViewTopScoreButton.Position    = [position(3)-150, position(4)-375, 125, 22];
            app.UIAxes.Position = [25, 25, position(3)-175, position(4)-25];
            
            app.started = false;
            app.replay = false;
            
            load_level(app,app.level);
            display_scores(app);
            app.StartButton.Text = 'Start';
            app.ViewTopScoreButton.Text = 'View Top Score';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SheepHerdingWindow = uifigure('Visible', 'off');
            app.SheepHerdingWindow.Name = 'Sheep Herding';
            app.SheepHerdingWindow.CloseRequestFcn = createCallbackFcn(app, @SheepHerdingWindowCloseRequest, true);
            app.SheepHerdingWindow.SizeChangedFcn = createCallbackFcn(app, @SheepHerdingWindowSizeChanged, true);
            app.SheepHerdingWindow.WindowKeyPressFcn = createCallbackFcn(app, @SheepHerdingWindowWindowKeyPress, true);
            app.SheepHerdingWindow.WindowKeyReleaseFcn = createCallbackFcn(app, @SheepHerdingWindowWindowKeyRelease, true);
            app.UIAxes = uiaxes(app.SheepHerdingWindow);
            title(app.UIAxes, {'Welcome: Enter your name'; 'cost = 0 '})
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.StartButton = uibutton(app.SheepHerdingWindow, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ButtonGroup = uibuttongroup(app.SheepHerdingWindow);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Level1Button = uiradiobutton(app.ButtonGroup);
            app.Level1Button.Text = 'Level 1';
            app.Level1Button.Value = true;
            app.Level2Button = uiradiobutton(app.ButtonGroup);
            app.Level2Button.Text = 'Level 2';
            app.Level3Button = uiradiobutton(app.ButtonGroup);
            app.Level3Button.Text = 'Level 3';
            app.Level4Button = uiradiobutton(app.ButtonGroup);
            app.Level4Button.Text = 'Level 4';
            app.TopScoresListBoxLabel = uilabel(app.SheepHerdingWindow);
            app.TopScoresListBoxLabel.Text = 'Top Scores';
            app.TopScoresListBox = uilistbox(app.SheepHerdingWindow);
            app.TopScoresListBox.Items = {'Score 1', 'Score 2', 'Score 3', 'Score 4', 'Score 5'};
            app.TopScoresListBox.ValueChangedFcn = createCallbackFcn(app, @TopScoresListBoxValueChanged, true);
            app.TopScoresListBox.Value = 'Score 1';
            app.ViewTopScoreButton = uibutton(app.SheepHerdingWindow, 'push');
            app.ViewTopScoreButton.ButtonPushedFcn = createCallbackFcn(app, @ViewTopScoreButtonPushed, true);
            app.ViewTopScoreButton.Text = 'View Top Score';
            app.EnterNameField = uieditfield(app.SheepHerdingWindow, 'text');
            app.EnterNameField.Value = 'Name';
            app.img_dog = uiimage(app.SheepHerdingWindow);
            app.img_dog.ImageSource = 'img_dog_alpha.png';
            app.img_target = uiimage(app.SheepHerdingWindow);
            app.img_target.ImageSource = 'img_target_alpha.png';
            % Show the figure after all components are created
            app.SheepHerdingWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SheepHerdingGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SheepHerdingWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SheepHerdingWindow)
        end
    end
end